classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UploadImageButton     matlab.ui.control.Button
        InpUIAxes             matlab.ui.control.UIAxes
        OpenNoiseToolButton   matlab.ui.control.Button
        OutUIAxes             matlab.ui.control.UIAxes
        SaveButton            matlab.ui.control.Button
        OpenFilterToolButton  matlab.ui.control.Button
    end


    properties (Access = public)
        filepath; % filepath of the input image
        filterImg % for filtering image files
        inputImg % input image
        defaultPic % default folder for pics
        outputImg % output image
        
    end

    methods (Access = public)
        
        function mainCallback(app, arg, img)
            app.outputImg = img;
            disp('I am in main call back');
            disp(size(app.outputImg));
            imshow(img, 'Parent', app.OutUIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s)
            title(app.InpUIAxes, []);
            xlabel(app.InpUIAxes, []);
            ylabel(app.InpUIAxes, []);
            app.InpUIAxes.XAxis.TickLabels = {};
            app.InpUIAxes.YAxis.TickLabels = {};
            
            title(app.OutUIAxes, []);
            xlabel(app.OutUIAxes, []);
            ylabel(app.OutUIAxes, []);
            app.OutUIAxes.XAxis.TickLabels = {};
            app.OutUIAxes.YAxis.TickLabels = {};   
            
            app.defaultPic = './default_pics/';
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg = imread([path,file]);
                 app.filepath = strcat(path,file);
                 disp(app.filepath);
                 imshow(app.inputImg, 'Parent', app.InpUIAxes);
            end
        end

        % Button pushed function: OpenNoiseToolButton
        function OpenNoiseToolButtonPushed(app, event)
            if (~ isempty(app.inputImg))
                noise(app,app.inputImg); 
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if (~isempty(app.outputImg))
                [file, path] = uiputfile(app.filterImg, 'Workspace File');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    filePath = strcat(path,file);
                    imwrite(app.outputImg, filePath);
                end
            end
        end

        % Button pushed function: OpenFilterToolButton
        function OpenFilterToolButtonPushed(app, event)
            if (~ isempty(app.inputImg))
                filterapp(app,app.inputImg); 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.InpUIAxes = uiaxes(app.UIFigure);
            title(app.InpUIAxes, 'Title')
            xlabel(app.InpUIAxes, 'X')
            ylabel(app.InpUIAxes, 'Y')
            app.InpUIAxes.XLim = [0 1];
            app.InpUIAxes.YLim = [0 1];
            app.InpUIAxes.ZLim = [0 1];
            app.InpUIAxes.CLim = [0 1];
            app.InpUIAxes.XTick = [0 0.5 1];
            app.InpUIAxes.YTick = [0 0.5 1];
            app.InpUIAxes.ZTick = [0 0.5 1];
            app.OpenNoiseToolButton = uibutton(app.UIFigure, 'push');
            app.OpenNoiseToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNoiseToolButtonPushed, true);
            app.OpenNoiseToolButton.Text = 'Open Noise Tool';
            app.OutUIAxes = uiaxes(app.UIFigure);
            title(app.OutUIAxes, 'Title')
            xlabel(app.OutUIAxes, 'X')
            ylabel(app.OutUIAxes, 'Y')
            app.OutUIAxes.XLim = [0 1];
            app.OutUIAxes.YLim = [0 1];
            app.OutUIAxes.ZLim = [0 1];
            app.OutUIAxes.CLim = [0 1];
            app.OutUIAxes.XTick = [0 0.5 1];
            app.OutUIAxes.YTick = [0 0.5 1];
            app.OutUIAxes.ZTick = [0 0.5 1];
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenFilterToolButton = uibutton(app.UIFigure, 'push');
            app.OpenFilterToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilterToolButtonPushed, true);
            app.OpenFilterToolButton.Text = 'Open Filter Tool ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end